classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ResultField           matlab.ui.control.NumericEditField
        ResultEditFieldLabel  matlab.ui.control.Label
        GetButton             matlab.ui.control.Button
        SentButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SentButton
        function SentButtonPushed(app, event)
                % ===== CHANGE THESE VALUES =====
            channelID = 3250036;          % e.g., 2012345
            writeAPIKey = 'K20Y1E389DS2SUPE';   % e.g., 'ABC123XYZ'
                % ===============================
    
                % Generate random data
            randomValue = randi([0, 100]);
    
    % Send to ThingSpeak
            url = sprintf('https://api.thingspeak.com/update?api_key=%s&field1=%d', ...
                  writeAPIKey, randomValue);
    
            webread(url);
    
    % Show confirmation
            app.ResultField.Value = randomValue;
            uialert(app.UIFigure, sprintf('Sent: %d', randomValue), 'Success');
        end

        % Button pushed function: GetButton
        function GetButtonPushed(app, event)
                % ===== CHANGE THESE VALUES =====
    channelID = 3250036;        % e.g., 2012345
    readAPIKey = '2NOO2GGNGT552D5G';   % e.g., 'XYZ789ABC'
    % ===============================
    
    % Get data from ThingSpeak
    url = sprintf('https://api.thingspeak.com/channels/%d/feeds/last.json?api_key=%s', ...
                  channelID, readAPIKey);
    
    data = webread(url);
    
    % Display result
    app.ResultField.Value = str2double(data.field1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SentButton = uibutton(app.UIFigure, 'push');
            app.SentButton.ButtonPushedFcn = createCallbackFcn(app, @SentButtonPushed, true);
            app.SentButton.Text = 'Sent';
            app.GetButton = uibutton(app.UIFigure, 'push');
            app.GetButton.ButtonPushedFcn = createCallbackFcn(app, @GetButtonPushed, true);
            app.GetButton.Text = 'Get';
            app.ResultEditFieldLabel = uilabel(app.UIFigure);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end